classdef EscherAppSlave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherSlaveFig                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        UDPConnectionTab               matlab.ui.container.Tab
        ListenBtn                      matlab.ui.control.Button
        SlaveIPLabel                   matlab.ui.control.Label
        SlaveIp                        matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        SlavePort                      matlab.ui.control.NumericEditField
        MasterIPLabel                  matlab.ui.control.Label
        MasterIp                       matlab.ui.control.EditField
        PortEditFieldLabel_2           matlab.ui.control.Label
        MasterPort                     matlab.ui.control.NumericEditField
        GetSlaveIPandMACBtn            matlab.ui.control.Button
        StatusEditFieldLabel           matlab.ui.control.Label
        ConnectionStatus               matlab.ui.control.EditField
        MACLabel                       matlab.ui.control.Label
        MasterMac                      matlab.ui.control.EditField
        MACLabel_2                     matlab.ui.control.Label
        SlaveMac                       matlab.ui.control.EditField
        VerboseCheckBox                matlab.ui.control.CheckBox
        ScreenTab                      matlab.ui.container.Tab
        ScreendistancecmLabel          matlab.ui.control.Label
        ScreenDistance                 matlab.ui.control.NumericEditField
        WidthcmLabel                   matlab.ui.control.Label
        ScreenWidth                    matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        ScreenHeight                   matlab.ui.control.NumericEditField
        CalModeRBGroup                 matlab.ui.container.ButtonGroup
        UncalibratedButton             matlab.ui.control.RadioButton
        CalibratedButton               matlab.ui.control.RadioButton
        ScreenformulaLabel             matlab.ui.control.Label
        ScreenFuncTxt                  matlab.ui.control.EditField
        LuminanceLabel                 matlab.ui.control.Label
        oculusCk                       matlab.ui.control.CheckBox
        OneScreenMode_ck               matlab.ui.control.CheckBox
        SphericaldistortioncorrectionCheckBox  matlab.ui.control.CheckBox
        StandbylumEditFieldLabel       matlab.ui.control.Label
        StandbyL                       matlab.ui.control.NumericEditField
        WidthpxlEditFieldLabel         matlab.ui.control.Label
        WidthPix                       matlab.ui.control.NumericEditField
        WidthpxlEditField_2Label       matlab.ui.control.Label
        HeightPix                      matlab.ui.control.NumericEditField
        HermesTab                      matlab.ui.container.Tab
        SynchronizationPanel           matlab.ui.container.Panel
        ard_ck                         matlab.ui.control.CheckBox
        HeightpxEditFieldLabel         matlab.ui.control.Label
        hhTxt                          matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel       matlab.ui.control.Label
        wwTxt                          matlab.ui.control.NumericEditField
        CuepositionPanel               matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CueTopRB                       matlab.ui.control.RadioButton
        CueBotRB                       matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        CueLeftRB                      matlab.ui.control.RadioButton
        CueRightRB                     matlab.ui.control.RadioButton
        DurationofcuemsEditFieldLabel  matlab.ui.control.Label
        optDtrTimeTxt                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        w % pointer to the open screen
        ifi % interval between frames
        black % value of black (usually 0)
        white % value of white (usually 255)
        screenRect % (4-element vector of the screen coordinates)
        ScreenBitDepth % bit depth of the colors (usually 8)
        HermesRect % Like screenRect but for Hermes
        OnePxAngle % Angle subtended by a single pixel
        ScreenFunc % Screen function (from 0-1 value to luminance)
        
        UdpObj % The actual UDP object
        
        ListeningFlag % This is 1 when listening is active, 0 when it's off
        BusyFlag % It's 1 if the slave is busy
    end
        
    methods (Access = public)
        
        function UdpDataReceivedCallback(app,obj,ev)
            
            % Read the datagram
            try
                DatagramIn = read(obj,1,"string");
                
                % Remove any white-space character. (Probably not needed
                % anymore after the upgrade to "udpport" from "udp" object.
                % Still here anyway)
                MsgIn = regexprep(DatagramIn.Data,'\s','');
                
                % Then split the message, according to the separator commas.
                MsgCellArray = strsplit(MsgIn,',');
                
                % If verbose, print the message to the command window
                if app.VerboseCheckBox.Value
                    fprintf('\nIncoming message from Master:\n')
                    for i=1:numel(MsgCellArray)
                        fprintf('\t %s \n',MsgCellArray{i})
                    end
                    fprintf('\n')
                end
            catch ERR
                warning('Error while reading UDP message \n  Datagram discarded\n')
                getReport(ERR)
                
            end
            % The first element of the cell array contains usually the MAC
            % address of the slave, but when the connection is started it
            % contains the word 'start'.
            if strcmp(MsgCellArray{1},'start')
                if ~ValidateIPv4(MsgCellArray{2}) || ~ValidateMac(MsgCellArray{3})
                    % If there is no valid IP or MAC
                    warning('Attempted UDP connection with invalid IP or Mac address')
                    return
                end
                
                % First of all, we must ask for confirmation to accept this
                % connection.
                selection = uiconfirm(app.EscherSlaveFig,['Accept connection from IP ',...
                    MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                if strcmp(selection,'Cancel')
                    return
                end
                
                % If the connection is accepted, we can go on.
                % Close any open screen.
                CloseScreen
                
                % The second and third word contain the master IP and MAC
                % respectively. The fourth is the master port.
                app.MasterIp.Value = MsgCellArray{2};
                app.MasterMac.Value = MsgCellArray{3};
                app.MasterPort.Value = str2double(MsgCellArray{4});
                
                % Set the remote (master) IP and port.
                str = struct("RemoteHost",app.MasterIp.Value,"RemotePort",app.MasterPort.Value);
                app.UdpObj.UserData = str;

                
                % The connection is set, so the buttons must be changed
                % accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                
                % Send a confirmation message, containing the word 'start', 
                % the IP and MAC addresses.
                UdpSendMessage(app,'start')
                
                fprintf('\nConnection with Master estabilished\n')
                
            elseif strcmp(MsgCellArray{1},app.MasterMac.Value)
                switch MsgCellArray{2}
                    
                    % The second word in the message contains the instruction.
                    case 'screen'
                        
                        % The screen was opened or closed successfully, so the parameters of the 
                        % screen are returned.
                        % 0 = screen has to be closed
                        % 1 = screen has to be opened
                        % 2 = screen parameters have to be refreshed. Can be
                        %     used to check the connection.
                        try
                            if strcmp(MsgCellArray{3},'0')
                                
                                % Close the screen
                                CloseScreen
                                
                                UdpSendMessage(app,'screenok','closed')
                                
                            else
                                
                                % First of all, set all parameters.
                                app.ScreenDistance.Value=str2double(MsgCellArray{4});
                                app.ScreenWidth.Value=str2double(MsgCellArray{5});
                                app.ScreenHeight.Value=str2double(MsgCellArray{6});
                                app.CalibratedButton.Value=str2double(MsgCellArray{7});
                                app.ScreenFuncTxt.Value=MsgCellArray{8};
                                app.ard_ck.Value=str2double(MsgCellArray{9});
                                app.wwTxt.Value=str2double(MsgCellArray{10});
                                app.hhTxt.Value=str2double(MsgCellArray{11});
                                app.optDtrTimeTxt.Value=str2double(MsgCellArray{12});
                                app.CueTopRB.Value=str2double(MsgCellArray{13});
                                app.CueLeftRB.Value=str2double(MsgCellArray{14});
                                app.OneScreenMode_ck.Value=str2double(MsgCellArray{15});
                                app.oculusCk.Value=str2double(MsgCellArray{16});
                                app.SphericaldistortioncorrectionCheckBox.Value=str2double(MsgCellArray{17});
                                
                                if strcmp(MsgCellArray{3},'1')
                                    
                                    % Only for when the screen has to be opened.
                                    % Close any open screen.
                                    CloseScreen
                                    
                                    % Open the screen.
                                    OpenScreen(app, app.StandbyL.Value)
                                    app.WidthPix.Value  = app.screenRect(3);
                                    app.HeightPix.Value = app.screenRect(4);
                                    
                                    UdpSendMessage(app,'screenok','open')
                                    
                                else
                                    
                                    % Only when the screen has to be refreshed.
                                    HermesRectCalc(app)
                                    PixelAngleCalc(app)
                                    
                                    UdpSendMessage(app,'screenok','parametersrefreshed')
                                end
                                
                                % The screen function (from 0-1 value to luminance,
                                % has to be saved into the variable.
                                app.ScreenFunc = app.ScreenFuncTxt.Value;


                            end
                        catch ERR
                            UdpSendMessage(app,'screenerror',ERR)
                        end
                        
                    case 'background'
%                         A single screen projection.
                        ard_flag = app.ard_ck.Value;
                        Blumi = str2double(MsgCellArray{3});
                        if app.CalibratedButton.Value
                            Blumi = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
                        end
                        Blumi = app.white*Blumi;
                        if ard_flag
                            HermesRectCalc(app)
                            BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                            BaselineScreen = [app.screenRect; app.HermesRect]';
                            
                            %     This is commented because in flashes, the optical DTR
                            %     duration is the same as the flash duration
                            %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                        else
                            BaselineColor = cast(Blumi, app.ScreenBitDepth);
                           	BaselineScreen = app.screenRect;
                        end
                        Screen('FillRect', app.w, BaselineColor, BaselineScreen); % paints the rectangle (entire screen)
                        Screen('Flip', app.w);
                        
                        UdpSendMessage(app,'screenok',MsgCellArray(3))
                        
                    case 'calibration'
                        switch MsgCellArray{3}
                            case 'b'
%                             If the calibration is 'b', the black
%                             calibration is performed (the screen becomes
%                             all white apart from the small Hermes patch).
%                             If the calibration is 'w', the white
%                             calibration is performed and the screen turns
%                             all black apart for the Hermes patch.
                            ScreenColor=app.white;
                            HermesColor=0;
                            case 'w'
                            ScreenColor=0;
                            HermesColor=app.white;
                        end
                        HermesRectCalc(app)
                        CalibColor = cast([[ScreenColor;ScreenColor;ScreenColor], [HermesColor;HermesColor;HermesColor]], app.ScreenBitDepth);
                        CalibScreen = [app.screenRect; app.HermesRect]';
                        
                        Screen('FillRect', app.w, CalibColor, CalibScreen);
                        Screen('Flip', app.w);

                        UdpSendMessage(app,'calibrationok',MsgCellArray(3))
                       
                    otherwise % all visual stimuli go here
                        % MsgCellArray{2} is a string specifying the kind of
                        %     stimulus type (eg: 'flash').
                        % MsgCellArray{3} is a double containing the
                        %     stimulus total time, computed by the Master.
                        % MsgCellArray elements from 4 to end contain the
                        %     parameters of the visual stimulus.
                        stimDur = MsgCellArray(3);
                        paramVect = cellfun(@str2double,MsgCellArray(4:end));
                        callVisStim(app,MsgCellArray{2},stimDur,num2cell(paramVect))
                end
            end
        end
        
        
        function UdpSendMessage(app,msgType,params)
            % Send messages to Master via UDP port
            switch msgType
                case 'start'
                    msg=strjoin([{'start'},{app.SlaveIp.Value},{app.SlaveMac.Value},...
                        {num2str(app.SlavePort.Value)},"0"],',');
                    
                case 'screenok' % Sends the dimensions of the screen to the master.
                    switch params
                        case 'open'
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{'open'},{num2str(app.screenRect(3))},...
                                {num2str(app.screenRect(4))},{num2str(app.OnePxAngle)}],',');

                        case 'closed'
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{'closed'}],',');
                        
                        case 'parametersrefreshed'
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{'parametersrefreshed'}],',');
                            
                        otherwise
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{'lumiupdated'},params],',');
                    end
                    
                case 'screenerror'
                    msg = strjoin([{app.SlaveMac.Value},{'screenerror'},...
                        {'See the slave command window for the error log'}],','); 
                
                case 'calibrationok'
                    msg = strjoin([{app.SlaveMac.Value},{'calibrationok'},params],',');
                    
                case 'wait'
                    msg = strjoin([{app.SlaveMac.Value},{'wait'},params],',');
                
                case 'stimerror'
                    msg = strjoin([{app.SlaveMac.Value},{'stimerror'},...
                        {'See the slave command window for the error log'}],','); 
                    
                otherwise % stimulation done. send the outcome to Master
                    msg = strjoin([{app.SlaveMac.Value},msgType,params],',');
                    
            end
            
            write(app.UdpObj,msg,"string",app.MasterIp.Value,app.MasterPort.Value)
            
            % If verbose, print the message to the command window
            if app.VerboseCheckBox.Value
                MsgCellArray = strsplit(msg,',');
                fprintf('\nMessage sent to Master (IP %s, port %i):\n', app.MasterIp.Value,app.MasterPort.Value)
                for i=1:numel(MsgCellArray)
                    fprintf('\t %s \n',MsgCellArray{i})
                end
                fprintf('\n')
            end
            
            if ~isempty(regexp(msgType,'error','once'))
                % Report the error.
                report = getReport(params);
                errors_i = regexp(report,'Error in '); % how long is the stack?
                if numel(errors_i) > 8
                    % shorten the report
                    report = report(1:errors_i(end-7)-1);
                end
                warning('off','backtrace')
                warning('AN ERROR OCCURRED!!!')
                warning(report)
                warning('on','backtrace')
            end
        end
        
        function PixelAngleCalc(app)
% Compute angular size of single pixel. This is the angle subtended by a 
% single pixel which is at the center of the screen.

% Number of pixels in the screen width.
            Wpx = app.screenRect(3);
% Screen width in centimeters.
            ScWidth = app.ScreenWidth.Value;
% Distance of the screen in centimeters.
            ScDist = app.ScreenDistance.Value;
% The angle of a single pixel is returned in degrees.
            app.OnePxAngle = 2*atand(ScWidth/Wpx/2/ScDist);
        end
        
        function HermesRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            app.HermesRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function callVisStim(app,stim_function,stimDur,ParameterVector)
            
            % Call the function that presents the visual stimulus.
            UdpSendMessage(app,'wait',stimDur)
            
            ERR = [];
            try
%                 switch stim_function
%                     case 'flash'
%                         [timestamps, stopped] = FlashPresent(app,ParameterVector);
%                     case 'slowflash'
%                         [timestamps,stopped] = SlowFlashPresent(app,ParameterVector);
%                     case 'grid'
%                         [timestamps, stopped] = GridPresent(app,ParameterVector);
%                     case 'dots'
%                         [timestamps, stopped] = DotsPresent(app,ParameterVector);
%                     case 'check'
%                         [timestamps, stopped] = CheckPresent(app,ParameterVector);
%                     case 'rec field'
%                         [order, timestamps, stopped] = ReceptiveFieldPresent(app,ParameterVector);
%                         r = app.screenRect([3,4]);
%                         n = ParameterVector(6);
%                         nrows = ParameterVector(7);
%                         ncols = ParameterVector(8);
%                         save(app.CurrentLogStruct.logName,'n','nrows','ncols','r','order')
%                     case 'retinotopy'
%                         [timestamps, stopped] = RetinotopyPresent(app,ParameterVector);
%                 end
                [timestamps,stopped] = feval(stim_function,app,ParameterVector);
                
            catch ERR
            end
            
            if isa(ERR,'MException')
                txt = 'stimerror';
                params = ERR;
            elseif stopped
                txt = 'stiminterrupted';
                params = strjoin([stim_function, ...
                {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
            else
                txt = 'stimok';
                params = strjoin([stim_function, ...
                {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
            end
            
            UdpSendMessage(app,txt,params)

        end
        
        function setupUDP(app)
            
            % Try to get the IP automatically
            GetSlaveIPandMACBtnPushed(app,[])
            
            % UDP object initialization.
            if ishandle(app.UdpObj); delete(app.UdpObj); end
            try
                app.UdpObj = udpport("datagram","IPV4",...
                    "Timeout",5, ...
                    "LocalHost",app.SlaveIp.Value, ...
                    "EnablePortSharing",false, ...
                    "LocalPort",app.SlavePort.Value, ...
                    "OutputDatagramSize", 2048);
            catch ERR
                if strcmp('Unable to bind to LOCALHOST and LOCALPORT. Verify that the LOCALPORT is not in use, and that LOCALHOST and LOCALPORT are valid for IPADDRESSVERSION "IPV4".',...
                        ERR.message)
                app.UdpObj = udpport("datagram","IPV4", ...
                    "LocalHost",app.SlaveIp.Value, ...
                    "Timeout",app.TimeoutTime.Value, ...
                    "EnablePortSharing",false, ...
                    "OutputDatagramSize", 2048);
                
                app.SlavePort.Value = app.UdpObj.LocalPort;
                app.SlavePort.BackgroundColor = [1 1 0]; % highlight the GUI element
                
                fprintf('Local UDP Port Changed! Port in use:\n\t%i',app.UdpObj.LocalPort)
                end
            end
            % Then starts listening for a UDP message.
            app.SlavePort.Value = app.UdpObj.LocalPort;

            % Sets the callback function for when data is received.
            configureCallback(app.UdpObj,"datagram",1,@(obj,ev) UdpDataReceivedCallback(app,obj,ev));

            app.ListeningFlag = 1;
            app.ListenBtn.Text = 'STOP Listening';
        end
        
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             Flag initialization.
            app.ListeningFlag = 0;
            
            setupUDP(app)
        end

        % Button pushed function: ListenBtn
        function ListenBtnButtonPushed(app, event)
            if ~app.ListeningFlag

                setupUDP(app)
%             Sets the callback function for when data is received.
%             configureCallback(app.UdpObj,"terminator",@(obj,~) UdpDataReceivedCallback(app,obj));

            else
%                 If the slave was already listening, pressing the button
%                 again stops it.
                
                configureCallback(app.UdpObj,"off");

                app.ListenBtn.Text = 'Start Listening';
%                 Re-enable the three boxes for slave connection parameters.
                set([app.SlaveIp,app.SlaveMac,app.SlavePort],'Editable',1)
                app.GetSlaveIPandMACBtn.Enable = 1;
                app.ListeningFlag = 0;
                delete(app.UdpObj)
            end
        end

        % Button pushed function: GetSlaveIPandMACBtn
        function GetSlaveIPandMACBtnPushed(app, event)
            ConnParams = GetConnParams;
            app.SlaveIp.Value = ConnParams.IP;
            app.SlaveMac.Value = ConnParams.MAC;
        end

        % Close request function: EscherSlaveFig
        function EscherSlaveFigCloseRequest(app, event)
            try
                CloseAllUdp
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            CloseScreen
            delete(app)
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            LogicValue = ValidateIPv4(app.SlaveIp.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveIp.Value = event.OldValue;
            end
        end

        % Value changed function: SlaveMac
        function SlaveMacValueChanged(app, event)
            LogicValue = ValidateMac(app.SlaveMac.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveMac.Value = event.OldValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherSlaveFig = uifigure('Visible', 'off');
            app.EscherSlaveFig.Name = 'EscherSlave';
            app.EscherSlaveFig.CloseRequestFcn = createCallbackFcn(app, @EscherSlaveFigCloseRequest, true);
            app.TabGroup = uitabgroup(app.EscherSlaveFig);
            app.UDPConnectionTab = uitab(app.TabGroup);
            app.UDPConnectionTab.Title = 'UDP Connection';
            app.ListenBtn = uibutton(app.UDPConnectionTab, 'push');
            app.ListenBtn.ButtonPushedFcn = createCallbackFcn(app, @ListenBtnButtonPushed, true);
            app.ListenBtn.Tooltip = {'Start listening for any incoming UDP message on the selected port.'};
            app.ListenBtn.Text = 'Start Listening';
            app.SlaveIPLabel = uilabel(app.UDPConnectionTab);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionTab, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'IP address of the slave (this app).'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionTab);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionTab, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionTab);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionTab, 'text');
            app.MasterIp.Editable = 'off';
            app.MasterIp.Tooltip = {'IP address of the master app. It is going to be given by the master when it starts the connection.'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionTab);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionTab, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Editable = 'off';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetSlaveIPandMACBtn = uibutton(app.UDPConnectionTab, 'push');
            app.GetSlaveIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetSlaveIPandMACBtnPushed, true);
            app.GetSlaveIPandMACBtn.Tooltip = {'Get the IP of this computer (slave).'};
            app.GetSlaveIPandMACBtn.Text = 'Get Slave IP and MAC';
            app.StatusEditFieldLabel = uilabel(app.UDPConnectionTab);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionTab, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel = uilabel(app.UDPConnectionTab);
            app.MACLabel.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionTab, 'text');
            app.MasterMac.Editable = 'off';
            app.MasterMac.Tooltip = {'Physical address of the master. It is going to be given by the master when it starts the connection.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionTab);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionTab, 'text');
            app.SlaveMac.ValueChangedFcn = createCallbackFcn(app, @SlaveMacValueChanged, true);
            app.SlaveMac.Tooltip = {'Physical address of the slave (this app).'};
            app.SlaveMac.Value = '000000000000';
            app.VerboseCheckBox = uicheckbox(app.UDPConnectionTab);
            app.VerboseCheckBox.Text = 'Verbose';
            app.ScreenTab = uitab(app.TabGroup);
            app.ScreenTab.Title = 'Screen';
            app.ScreendistancecmLabel = uilabel(app.ScreenTab);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.ScreenTab, 'numeric');
            app.ScreenDistance.Limits = [0 Inf];
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.Editable = 'off';
            app.WidthcmLabel = uilabel(app.ScreenTab);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.ScreenTab, 'numeric');
            app.ScreenWidth.Editable = 'off';
            app.HeightcmEditFieldLabel = uilabel(app.ScreenTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.ScreenTab, 'numeric');
            app.ScreenHeight.Editable = 'off';
            app.CalModeRBGroup = uibuttongroup(app.ScreenTab);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Enable = 'off';
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Enable = 'off';
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.ScreenformulaLabel = uilabel(app.CalModeRBGroup);
            app.ScreenformulaLabel.Text = 'Screen formula:';
            app.ScreenFuncTxt = uieditfield(app.CalModeRBGroup, 'text');
            app.ScreenFuncTxt.Editable = 'off';
            app.LuminanceLabel = uilabel(app.CalModeRBGroup);
            app.LuminanceLabel.Text = 'Luminance=';
            app.oculusCk = uicheckbox(app.ScreenTab);
            app.oculusCk.Enable = 'off';
            app.oculusCk.Text = 'VR headset';
            app.OneScreenMode_ck = uicheckbox(app.ScreenTab);
            app.OneScreenMode_ck.Enable = 'off';
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.SphericaldistortioncorrectionCheckBox = uicheckbox(app.ScreenTab);
            app.SphericaldistortioncorrectionCheckBox.Enable = 'off';
            app.SphericaldistortioncorrectionCheckBox.Text = 'Apply distortion';
            app.StandbylumEditFieldLabel = uilabel(app.ScreenTab);
            app.StandbylumEditFieldLabel.Text = 'Standby luminance:';
            app.StandbyL = uieditfield(app.ScreenTab, 'numeric');
            app.WidthpxlEditFieldLabel = uilabel(app.ScreenTab);
            app.WidthpxlEditFieldLabel.Text = 'Width (pxl)';
            app.WidthPix = uieditfield(app.ScreenTab, 'numeric');
            app.WidthPix.Editable = 'off';
            app.WidthpxlEditField_2Label = uilabel(app.ScreenTab);
            app.WidthpxlEditField_2Label.Text = 'Width (pxl)';
            app.HeightPix = uieditfield(app.ScreenTab, 'numeric');
            app.HeightPix.Editable = 'off';
            app.HermesTab = uitab(app.TabGroup);
            app.HermesTab.Title = 'Hermes';
            app.SynchronizationPanel = uipanel(app.HermesTab);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ard_ck = uicheckbox(app.SynchronizationPanel);
            app.ard_ck.Enable = 'off';
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.HeightpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.hhTxt.Editable = 'off';
            app.CueWidthpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.wwTxt.Editable = 'off';
            app.CuepositionPanel = uipanel(app.SynchronizationPanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup = uibuttongroup(app.CuepositionPanel);
            app.CueTopRB = uiradiobutton(app.ButtonGroup);
            app.CueTopRB.Enable = 'off';
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.ButtonGroup);
            app.CueBotRB.Enable = 'off';
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_2);
            app.CueLeftRB.Enable = 'off';
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.ButtonGroup_2);
            app.CueRightRB.Enable = 'off';
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.DurationofcuemsEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Editable = 'off';
            % Show the figure after all components are created
            app.EscherSlaveFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppSlave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherSlaveFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherSlaveFig)
        end
    end
end